classdef AppJuego < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SumarTab                    matlab.ui.container.Tab
        Puntaje                     matlab.ui.control.NumericEditField
        PuntajeEditFieldLabel       matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        ResultadoLampLabel          matlab.ui.control.Label
        EmpezarButton               matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RestarTab                   matlab.ui.container.Tab
        Puntaje_2                   matlab.ui.control.NumericEditField
        PuntajeEditFieldLabel_2     matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        ResultadoLampLabel_2        matlab.ui.control.Label
        EmpezarButton_2             matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        MultiplicarTab              matlab.ui.container.Tab
        Puntaje_3                   matlab.ui.control.NumericEditField
        PuntajeEditFieldLabel_3     matlab.ui.control.Label
        Lamp_3                      matlab.ui.control.Lamp
        ResultadoLampLabel_3        matlab.ui.control.Label
        EmpezarButton_3             matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
        DividirTab                  matlab.ui.container.Tab
        Puntaje_4                   matlab.ui.control.NumericEditField
        PuntajeEditFieldLabel_4     matlab.ui.control.Label
        Lamp_4                      matlab.ui.control.Lamp
        ResultadoLampLabel_4        matlab.ui.control.Label
        EmpezarButton_4             matlab.ui.control.Button
        Image4                      matlab.ui.control.Image
        JuegaconlasMatemticasLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EmpezarButton
        function EmpezarButtonPushed(app, event)
            sumando1 = [25 40 16];
            sumando2 = [21 23 11];
            %total = sumando1 + sumando2;
            puntaje = 0;
            app.Puntaje.Value = puntaje;
            %idx = randi(4); % enteros aleatorios entre 1 y 4

            for c = 1:length(sumando2)
                res = sumando1(c) + sumando2(c);
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = '';
                % Use the TeX interpreter to format the question
                cadena = '¿Cual es el resultado de la siguiente operación? \n\n %i + %i \n';
                quest = sprintf(cadena,sumando1(c),sumando2(c));

                opc = {num2str(res),num2str(randi(100)),num2str(randi(100))};
                opc_rand = opc(randperm(length(opc)));

                answer = questdlg(quest,'Resuelve la siguiente suma:',opc_rand(1),opc_rand(2),opc_rand(3),opts);
                recuperar = str2double(answer);

                if recuperar == res
                    puntaje = puntaje + 10;
                    app.Lamp.Color = 'g';
                    app.Puntaje.Value = puntaje;
                else
                    app.Lamp.Color = 'r';
                    app.Puntaje.Value = puntaje;
                    clc
                end

            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clc;
            clear all;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.JuegaconlasMatemticasLabel = uilabel(app.UIFigure);
            app.JuegaconlasMatemticasLabel.Text = {'Juega con las'; 'Matemáticas'};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SumarTab = uitab(app.TabGroup);
            app.SumarTab.Title = 'Sumar';
            app.Image = uiimage(app.SumarTab);
            app.Image.ImageSource = 'sumaim.jpg';
            app.EmpezarButton = uibutton(app.SumarTab, 'push');
            app.EmpezarButton.ButtonPushedFcn = createCallbackFcn(app, @EmpezarButtonPushed, true);
            app.EmpezarButton.Text = {'Empezar'; ''};
            app.ResultadoLampLabel = uilabel(app.SumarTab);
            app.ResultadoLampLabel.Text = {'Resultado'; ''};
            app.Lamp = uilamp(app.SumarTab);
            app.PuntajeEditFieldLabel = uilabel(app.SumarTab);
            app.PuntajeEditFieldLabel.Text = 'Puntaje';
            app.Puntaje = uieditfield(app.SumarTab, 'numeric');
            app.Puntaje.Editable = 'off';
            app.RestarTab = uitab(app.TabGroup);
            app.RestarTab.Title = 'Restar';
            app.Image2 = uiimage(app.RestarTab);
            app.Image2.ImageSource = 'restarim.jpg';
            app.EmpezarButton_2 = uibutton(app.RestarTab, 'push');
            app.EmpezarButton_2.Text = {'Empezar'; ''};
            app.ResultadoLampLabel_2 = uilabel(app.RestarTab);
            app.ResultadoLampLabel_2.Text = {'Resultado'; ''};
            app.Lamp_2 = uilamp(app.RestarTab);
            app.PuntajeEditFieldLabel_2 = uilabel(app.RestarTab);
            app.PuntajeEditFieldLabel_2.Text = 'Puntaje';
            app.Puntaje_2 = uieditfield(app.RestarTab, 'numeric');
            app.Puntaje_2.Editable = 'off';
            app.MultiplicarTab = uitab(app.TabGroup);
            app.MultiplicarTab.Title = 'Multiplicar';
            app.Image3 = uiimage(app.MultiplicarTab);
            app.Image3.ImageSource = 'multip.jpg';
            app.EmpezarButton_3 = uibutton(app.MultiplicarTab, 'push');
            app.EmpezarButton_3.Text = {'Empezar'; ''};
            app.ResultadoLampLabel_3 = uilabel(app.MultiplicarTab);
            app.ResultadoLampLabel_3.Text = {'Resultado'; ''};
            app.Lamp_3 = uilamp(app.MultiplicarTab);
            app.PuntajeEditFieldLabel_3 = uilabel(app.MultiplicarTab);
            app.PuntajeEditFieldLabel_3.Text = 'Puntaje';
            app.Puntaje_3 = uieditfield(app.MultiplicarTab, 'numeric');
            app.Puntaje_3.Editable = 'off';
            app.DividirTab = uitab(app.TabGroup);
            app.DividirTab.Title = 'Dividir';
            app.Image4 = uiimage(app.DividirTab);
            app.Image4.ImageSource = 'la-division.jpg';
            app.EmpezarButton_4 = uibutton(app.DividirTab, 'push');
            app.EmpezarButton_4.Text = {'Empezar'; ''};
            app.ResultadoLampLabel_4 = uilabel(app.DividirTab);
            app.ResultadoLampLabel_4.Text = {'Resultado'; ''};
            app.Lamp_4 = uilamp(app.DividirTab);
            app.PuntajeEditFieldLabel_4 = uilabel(app.DividirTab);
            app.PuntajeEditFieldLabel_4.Text = 'Puntaje';
            app.Puntaje_4 = uieditfield(app.DividirTab, 'numeric');
            app.Puntaje_4.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppJuego

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end